classdef JMPTaskCreation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CloseButton                matlab.ui.control.Button
        BodiesEditField            matlab.ui.control.EditField
        BodiesEditFieldLabel       matlab.ui.control.Label
        AddBodiesButton            matlab.ui.control.Button
        JointsEditField            matlab.ui.control.EditField
        JointsEditFieldLabel       matlab.ui.control.Label
        AddJointsButton            matlab.ui.control.Button
        AddMarkersButton           matlab.ui.control.Button
        MarkersEditField           matlab.ui.control.EditField
        MarkersEditFieldLabel      matlab.ui.control.Label
        EndTimeField               matlab.ui.control.NumericEditField
        ToLabel                    matlab.ui.control.Label
        StartTimeField             matlab.ui.control.NumericEditField
        TimeRangesEditFieldLabel   matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
        MarkerFileSearchButton     matlab.ui.control.Button
        MarkersFileEditField       matlab.ui.control.EditField
        MarkersFileEditFieldLabel  matlab.ui.control.Label
        EnabledCheckBox            matlab.ui.control.CheckBox
        TaskNameEditField          matlab.ui.control.EditField
        TaskNameEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = {?ObjectSelectionWindow})
        JMPBase;
        TaskApp JMPTaskCreation;
        markerFileName string;
        selectedMarkers string;
        JMPTask = struct();
    end
    
    methods (Access = public)
        
        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
            app.selectedMarkers = objects;
            app.onSelectedMarkersChanged();
        end
    end
    
    methods (Access = private)
        
        function onSelectedMarkersChanged(app)
            app.MarkersEditField.Value = strjoin(app.selectedMarkers, ", ");
        end

        function parseMarkerFile(app)
            import org.opensim.modeling.TimeSeriesTableVec3
            if ~exist(app.markerFileName)
                return
            end
            timeSeriesTable = TimeSeriesTableVec3(app.markerFileName);
            numTimePoints = timeSeriesTable.getNumRows;
            timeVector = timeSeriesTable.getIndependentColumn;
            startTime = timeVector.get(0);
            endTime = timeVector.get(numTimePoints-1);
            app.StartTimeField.Value = double(startTime);
            app.EndTimeField.Value = double(endTime);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPBase)
            app.JMPBase = JMPBase;
        end

        % Button pushed function: MarkerFileSearchButton
        function MarkerFileSearchButtonPushed(app, event)
        [file, path] = uigetfile('*.trc', "Select a trc marker file.");
        % User hit "Cancel"
        if isequal(file, 0)
            return
        end
  
        fullpath = fullfile(path, file);
    
        % Update the text field
        app.MarkersFileEditField.Value = fullpath;
        app.markerFileName = fullpath;

        parseMarkerFile(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.JMPBase.NumTasks = app.JMPBase.NumTasks + 1;
            app.JMPBase.JMPTaskList{app.JMPBase.NumTasks} = app.JMPTask;

            delete(app.UIFigure);
        end

        % Button pushed function: AddMarkersButton
        function AddMarkersButtonPushed(app, event)
            ObjectSelectionWindow(app, app.JMPBase.model_markers, ...
                app.selectedMarkers);
        end

        % Button pushed function: AddJointsButton
        function AddJointsButtonPushed(app, event)

        end

        % Button pushed function: AddBodiesButton
        function AddBodiesButtonPushed(app, event)

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: MarkersEditField
        function MarkersEditFieldValueChanged(app, event)
            parseMarkerFile(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TaskNameEditFieldLabel = uilabel(app.UIFigure);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.TaskNameEditField = uieditfield(app.UIFigure, 'text');
            app.TaskNameEditField.Value = 'Default';
            app.EnabledCheckBox = uicheckbox(app.UIFigure);
            app.EnabledCheckBox.Text = 'Enabled';
            app.MarkersFileEditFieldLabel = uilabel(app.UIFigure);
            app.MarkersFileEditFieldLabel.Text = 'Markers File';
            app.MarkersFileEditField = uieditfield(app.UIFigure, 'text');
            app.MarkerFileSearchButton = uibutton(app.UIFigure, 'push');
            app.MarkerFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileSearchButtonPushed, true);
            app.MarkerFileSearchButton.Icon = fullfile(pathToMLAPP, 'folder-open.svg');
            app.MarkerFileSearchButton.Text = '';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TimeRangesEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRangesEditFieldLabel.Text = 'Time Range (s):';
            app.StartTimeField = uieditfield(app.UIFigure, 'numeric');
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.EndTimeField = uieditfield(app.UIFigure, 'numeric');
            app.MarkersEditFieldLabel = uilabel(app.UIFigure);
            app.MarkersEditFieldLabel.Text = 'Markers';
            app.MarkersEditField = uieditfield(app.UIFigure, 'text');
            app.MarkersEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersEditFieldValueChanged, true);
            app.MarkersEditField.Editable = 'off';
            app.AddMarkersButton = uibutton(app.UIFigure, 'push');
            app.AddMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @AddMarkersButtonPushed, true);
            app.AddMarkersButton.Text = 'Add Markers';
            app.AddJointsButton = uibutton(app.UIFigure, 'push');
            app.AddJointsButton.ButtonPushedFcn = createCallbackFcn(app, @AddJointsButtonPushed, true);
            app.AddJointsButton.Text = 'Add Joints';
            app.JointsEditFieldLabel = uilabel(app.UIFigure);
            app.JointsEditFieldLabel.Text = 'Joints';
            app.JointsEditField = uieditfield(app.UIFigure, 'text');
            app.JointsEditField.Editable = 'off';
            app.AddBodiesButton = uibutton(app.UIFigure, 'push');
            app.AddBodiesButton.ButtonPushedFcn = createCallbackFcn(app, @AddBodiesButtonPushed, true);
            app.AddBodiesButton.Text = 'Add Bodies';
            app.BodiesEditFieldLabel = uilabel(app.UIFigure);
            app.BodiesEditFieldLabel.Text = 'Bodies';
            app.BodiesEditField = uieditfield(app.UIFigure, 'text');
            app.BodiesEditField.Editable = 'off';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPTaskCreation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end